classdef EnergyDispersionRelation2DGraphite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        aEditFieldLabel    matlab.ui.control.Label
        aEditField         matlab.ui.control.NumericEditField
        PlotButton         matlab.ui.control.Button
        energyDispersion   matlab.ui.control.UIAxes
        e2pEditFieldLabel  matlab.ui.control.Label
        e2pEditField       matlab.ui.control.NumericEditField
        sEditFieldLabel    matlab.ui.control.Label
        sEditField         matlab.ui.control.NumericEditField
        tEditFieldLabel    matlab.ui.control.Label
        tEditField         matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            close all;
            nano = 10^-9;
            a = app.aEditField.Value;
            %phi_a = 1;
            %phi_b = 1;
            %energy dispersion relation
            e2p = 0;
            t = -3.033;
            s = 0.129;
            i = 1;
            j = 1;
            k_x = -sqrt(3)*pi/a :0.1: sqrt(3)*pi/a;
            k_y = -pi:0.1:pi;
            for i = 1:length(k_y)
                for j = 1:length(k_x)
                    omega = sqrt(1+4*cos(sqrt(3)*k_x(j)*a/2)*cos(k_y(i)*a/2)+ 4*cos(k_y(i)*a/2)*cos(k_y(i)*a/2));
                    Eg2Dp (i,j) = (e2p + t*omega)/(1 + s*omega);
                    Eg2Dn (i,j) = (e2p - t*omega)/(1 - s*omega);
                end
                j = 1;
            end
            surf(app.energyDispersion, k_x, k_y,  Eg2Dp);
            hold(app.energyDispersion, 'on');
            surf(app.energyDispersion, k_x, k_y, Eg2Dn);
            colorbar(app.energyDispersion);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = 1;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.energyDispersion = uiaxes(app.UIFigure);
            title(app.energyDispersion, 'Energy dispersion relation')
            xlabel(app.energyDispersion, 'X')
            ylabel(app.energyDispersion, 'Y')
            app.e2pEditFieldLabel = uilabel(app.UIFigure);
            app.e2pEditFieldLabel.Text = 'e2p';
            app.e2pEditField = uieditfield(app.UIFigure, 'numeric');
            app.sEditFieldLabel = uilabel(app.UIFigure);
            app.sEditFieldLabel.Text = 's';
            app.sEditField = uieditfield(app.UIFigure, 'numeric');
            app.sEditField.Value = 0.129;
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't';
            app.tEditField = uieditfield(app.UIFigure, 'numeric');
            app.tEditField.Value = -3.033;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EnergyDispersionRelation2DGraphite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end