classdef montecarlovsmasterequation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Gamma                         matlab.ui.control.Button
        Plot                          matlab.ui.control.UIAxes
        maximumnumberofelectronscontainedindotnLabel  matlab.ui.control.Label
        n                             matlab.ui.control.Spinner
        GatevoltageVgEditFieldLabel   matlab.ui.control.Label
        GatevoltageVgEditField        matlab.ui.control.NumericEditField
        DrainVoltageVdEditFieldLabel  matlab.ui.control.Label
        DrainVoltageVdEditField       matlab.ui.control.NumericEditField
        GammapVd                      matlab.ui.control.Button
        GammapVg                      matlab.ui.control.Button
        IdvsVd                        matlab.ui.control.Button
        IdvsT                         matlab.ui.control.Button
        NtoDrain                      matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function y=master_equation_app(app, V,Vg,N)
            
            clear ma;
            for i=-N:1:N
                ma(N+i+1,N+i+2)=-(f_tunnel(1,i,V,Vg)+f_tunnel(2,i,V,Vg)+f_tunnel(3,i,V,Vg)+f_tunnel(4,i,V,Vg));
                ma(N+i+1,N+i+2-1)=f_tunnel(1,i-1,V,Vg)+f_tunnel(4,i-1,V,Vg); ma(N+i+1,N+i+2+1)=f_tunnel(2,i+1,V,Vg)+f_tunnel(3,i+1,V,Vg);
            end;
            mma=[1,zeros(1,2*N+2);ma;zeros(1,2*N+2),1];
            [p,o]=eig(mma);
            [s,posiz]=min(abs(diag(o)));
            p_n(:,1)=abs(p(:,posiz))/sum(abs(p(:,posiz)));
            for i=1:length(p_n)
                contr(i)=p_n(i)*(f_tunnel(1,i-N-2,V,Vg)-f_tunnel(2,i-N-2,V,Vg));
            end;
            plot(app.Plot, contr);
            hold(app.Plot, 'on');
            y=sum(contr);
        end;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Gamma
        function GammaPushed(app, event)
            clf(app.Plot);
            hold(app.Plot, 'off');
            y = [];
            n = app.n.Value;
            for i = -n : n
                y = [y; f_tunnel0(1,i,app.DrainVoltageVdEditField.Value,app.GatevoltageVgEditField.Value)];
            end;
            %%% plot dei 4 tipi di gamma(n) per n che va da -10 a 10
            app.Plot.Title.String="\Gamma";
            plot(app.Plot,-n:n, y(:,1), -n:n, y(:,2), -n:n, y(:,3), -n:n, y(:,4));
            xlabel (app.Plot,'Numero di cariche nel dot (n)');
            ylabel (app.Plot,'Rate di tunneling (\Gamma_n)');
            legend (app.Plot, '\Gamma_{source->dot}', '\Gamma_{dot->source}', '\Gamma_{dot->drain}', '\Gamma_{drain->dot}')
        end

        % Button pushed function: GammapVd
        function GammapVdButtonPushed(app, event)
            clf(app.Plot);
            hold(app.Plot, 'off');
            Vd=app.DrainVoltageVdEditField.Value;
            Vg=app.GatevoltageVgEditField.Value;
            N=app.n.Value;
            app.Plot.Title.String='plot del termine [p(n)*(\Gamma_{dot->R})(N) - (\Gamma_{R->dot})(N)] al variare di Vd';
            for i = 1:50
                corrente(i) = app.master_equation_app(exp(0.04*i)-1, Vg, N);
            end;
            xlabel (app.Plot, 'Tensione di drain (V)');
            ylabel (app.Plot, 'p(n)*[(\Gamma_{dot->R})(N) - (\Gamma_{R->dot})(N)]');
            %legend (num2str(exp(0.04*[1:50])));
        end

        % Button pushed function: GammapVg
        function GammapVgButtonPushed(app, event)
            clf(app.Plot);
            hold(app.Plot, 'off');
            Vd=app.DrainVoltageVdEditField.Value;
            Vg=app.GatevoltageVgEditField.Value;
            N=app.n.Value;
            for i=1:50
                corrente(i)=app.master_equation_app(Vd, exp(0.04*i)-1, N);
            end;
        end

        % Button pushed function: IdvsVd
        function IdvsVdPushed(app, event)
            clf(app.Plot);
            hold(app.Plot, 'off');
            clear corrente;
            for i = 1:50
                corrente(i) = master_equation(exp(0.04*i)-1, app.GatevoltageVgEditField.Value, app.n.Value);
            end;
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            %%%% plot della corrente di drain (curd) al variare di V, avendo fissato Vg=0.5, confronto col risultato master equation
            app.Plot.Title.String='Plot della corrente di drain (curd) al variare di V_d, avendo fissato V_g';
            plot(app.Plot, exp(0.04*[1:50])-1, (curd(length(curd),:))./sum(t(:,:)), exp(0.04*[1:50])-1, corrente);
            xlabel (app.Plot, 'Tensione di drain (V_d)');
            ylabel (app.Plot, 'Corrente di drain (I_d)');
            legend (app.Plot, 'Monte Carlo', 'Master Equations');
        end

        % Button pushed function: IdvsT
        function IdvsTButtonPushed(app, event)
            clf(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            clear corr_parz;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            %%%% plot della corrente di drain (curd), al variare del tempo, avendo fissato V=exp(0.04*10) e Vg=0.5
            app.Plot.Title.String='Plot di I_d al variare del tempo, avendo fissato V_d=exp(0.04*10) e Vg';
            plot(app.Plot, corr_parz);
            xlabel (app.Plot, 'Tempo');
            ylabel (app.Plot, 'Corrente di drain (curd)');
        end

        % Button pushed function: NtoDrain
        function NtoDrainButtonPushed(app, event)
            clf(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            clear corr_parz;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            %%% numero di cariche arrivate al drain al variare del tempo, avendo fissato V=exp(0.04*10) e Vg=0.5
            app.Plot.Title.String='numero di cariche arrivate al drain al variare del tempo, avendo fissato V=exp(0.04*10) e Vg=0.5';
            plot(app.Plot, curd(:,10));
            xlabel (app.Plot, 'Tempo');
            ylabel (app.Plot, 'Cariche arrivate al drain');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Gamma = uibutton(app.UIFigure, 'push');
            app.Gamma.ButtonPushedFcn = createCallbackFcn(app, @GammaPushed, true);
            app.Gamma.Text = 'Plot Γ';
            app.Plot = uiaxes(app.UIFigure);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.maximumnumberofelectronscontainedindotnLabel = uilabel(app.UIFigure);
            app.maximumnumberofelectronscontainedindotnLabel.Text = 'maximum number of electrons contained in dot (n)';
            app.n = uispinner(app.UIFigure);
            app.n.Value = 10;
            app.GatevoltageVgEditFieldLabel = uilabel(app.UIFigure);
            app.GatevoltageVgEditFieldLabel.Text = 'Gate voltage (Vg)';
            app.GatevoltageVgEditField = uieditfield(app.UIFigure, 'numeric');
            app.GatevoltageVgEditField.Value = 5;
            app.DrainVoltageVdEditFieldLabel = uilabel(app.UIFigure);
            app.DrainVoltageVdEditFieldLabel.Text = 'Drain Voltage (Vd)';
            app.DrainVoltageVdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrainVoltageVdEditField.Value = 2;
            app.GammapVd = uibutton(app.UIFigure, 'push');
            app.GammapVd.ButtonPushedFcn = createCallbackFcn(app, @GammapVdButtonPushed, true);
            app.GammapVd.Text = 'Plot Γ*p(n) in relation of Vd';
            app.GammapVg = uibutton(app.UIFigure, 'push');
            app.GammapVg.ButtonPushedFcn = createCallbackFcn(app, @GammapVgButtonPushed, true);
            app.GammapVg.Text = 'Plot Γ*p(n) in relation of Vg';
            app.IdvsVd = uibutton(app.UIFigure, 'push');
            app.IdvsVd.ButtonPushedFcn = createCallbackFcn(app, @IdvsVdPushed, true);
            app.IdvsVd.Text = {'Plot Id al variare di Vd, avendo '; 'fissato Vg,confronto col '; 'risultato master equation '};
            app.IdvsT = uibutton(app.UIFigure, 'push');
            app.IdvsT.ButtonPushedFcn = createCallbackFcn(app, @IdvsTButtonPushed, true);
            app.IdvsT.Text = {'Plot di Id al variare del tempo, '; 'avendo fissato '; 'Vd=exp(0.04*10) e Vg'};
            app.NtoDrain = uibutton(app.UIFigure, 'push');
            app.NtoDrain.ButtonPushedFcn = createCallbackFcn(app, @NtoDrainButtonPushed, true);
            app.NtoDrain.Text = {'numero di cariche arrivate al drain'; 'al variare del tempo, avendo fissato'; 'Vd=exp(0.04*10) e Vg'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montecarlovsmasterequation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end