classdef montecarlovsmasterequation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Gamma                         matlab.ui.control.Button
        Plot                          matlab.ui.control.UIAxes
        MaximumnumberofelectronsstoredinthedotnLabel  matlab.ui.control.Label
        n                             matlab.ui.control.Spinner
        GatevoltageVgEditFieldLabel   matlab.ui.control.Label
        GatevoltageVgEditField        matlab.ui.control.NumericEditField
        DrainVoltageVdEditFieldLabel  matlab.ui.control.Label
        DrainVoltageVdEditField       matlab.ui.control.NumericEditField
        GammapVd                      matlab.ui.control.Button
        GammapVg                      matlab.ui.control.Button
        IdvsVd                        matlab.ui.control.Button
        IdvsT                         matlab.ui.control.Button
        chargesArrivedToDrain         matlab.ui.control.Button
        cargesLeftTheSource           matlab.ui.control.Button
        netCharges                    matlab.ui.control.Button
        drainCurrentVaryVg            matlab.ui.control.Button
        drainCurrentVsTime            matlab.ui.control.Button
        masterEquationVsMonteCarlo    matlab.ui.control.Button
        chargeStored                  matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function y=master_equation_app(app, V,Vg,N)
            
            clear ma;
            for i=-N:1:N
                ma(N+i+1,N+i+2)=-(f_tunnel(1,i,V,Vg)+f_tunnel(2,i,V,Vg)+f_tunnel(3,i,V,Vg)+f_tunnel(4,i,V,Vg));
                ma(N+i+1,N+i+2-1)=f_tunnel(1,i-1,V,Vg)+f_tunnel(4,i-1,V,Vg); ma(N+i+1,N+i+2+1)=f_tunnel(2,i+1,V,Vg)+f_tunnel(3,i+1,V,Vg);
            end;
            mma=[1,zeros(1,2*N+2);ma;zeros(1,2*N+2),1];
            [p,o]=eig(mma);
            [s,posiz]=min(abs(diag(o)));
            p_n(:,1)=abs(p(:,posiz))/sum(abs(p(:,posiz)));
            for i=1:length(p_n)
                contr(i)=p_n(i)*(f_tunnel(1,i-N-2,V,Vg)-f_tunnel(2,i-N-2,V,Vg));
            end;
            plot(app.Plot, contr);
            hold(app.Plot, 'on');
            y=sum(contr);
        end;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Gamma
        function GammaPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            y = [];
            n = app.n.Value;
            for i = -n : n
                y = [y; f_tunnel0(1,i,app.DrainVoltageVdEditField.Value,app.GatevoltageVgEditField.Value)];
            end;
            %%% plot dei 4 tipi di gamma(n) per n che va da -10 a 10
            app.Plot.Title.String="\Gamma";
            plot(app.Plot,-n:n, y(:,1), -n:n, y(:,2), -n:n, y(:,3), -n:n, y(:,4));
            xlabel (app.Plot,'Number of charges in dot (n)');
            ylabel (app.Plot,'Tunneling rate (\Gamma_n)');
            legend (app.Plot, '\Gamma_{source \rightarrow dot}', '\Gamma_{dot \rightarrow source}', '\Gamma_{dot \rightarrow drain}', '\Gamma_{drain \rightarrow dot}')
        end

        % Button pushed function: GammapVd
        function GammapVdButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            Vd=app.DrainVoltageVdEditField.Value;
            Vg=app.GatevoltageVgEditField.Value;
            N=app.n.Value;
            app.Plot.Title.String='plot of [p(n)*(\Gamma_{dot \rightarrow R})(N) - (\Gamma_{R \rightarrow dot})(N)] to vary of V_d';
            for i = 1:50
                corrente(i) = app.master_equation_app(exp(0.04*i)-1, Vg, N);
            end;
            xlabel (app.Plot, 'Drain voltage (V_d)');
            ylabel (app.Plot, 'p(n)*[(\Gamma_{dot \rightarrow R})(N) - (\Gamma_{R \rightarrow dot})(N)]');
            %legend (num2str(exp(0.04*[1:50])));
        end

        % Button pushed function: GammapVg
        function GammapVgButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            Vd=app.DrainVoltageVdEditField.Value;
            Vg=app.GatevoltageVgEditField.Value;
            N=app.n.Value;
            for i=1:50
                corrente(i)=app.master_equation_app(Vd, exp(0.04*i)-1, N);
            end;
        end

        % Button pushed function: IdvsVd
        function IdvsVdPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            clear corrente;
            for i = 1:50
                corrente(i) = master_equation(exp(0.04*i)-1, app.GatevoltageVgEditField.Value, app.n.Value);
            end;
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            %%%% plot della corrente di drain (curd) al variare di V, avendo fissato Vg=0.5, confronto col risultato master equation
            app.Plot.Title.String='Plot of drain curren to vary of V_d';
            plot(app.Plot, exp(0.04*[1:50])-1, (curd(length(curd),:))./sum(t(:,:)), exp(0.04*[1:50])-1, corrente);
            xlabel (app.Plot, 'Drain voltage (V_d)');
            ylabel (app.Plot, 'Drain current (I_d)');
            legend (app.Plot, 'Monte Carlo', 'Master Equations');
        end

        % Button pushed function: IdvsT
        function IdvsTButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            clear corr_parz;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            %%%% plot della corrente di drain (curd), al variare del tempo, avendo fissato V=exp(0.04*10) e Vg=0.5
            app.Plot.Title.String='Plot of I_d in relation to time, V_d=exp(0.04*10)';
            plot(app.Plot, corr_parz);
            xlabel (app.Plot, 'Time');
            ylabel (app.Plot, 'I_d');
        end

        % Button pushed function: chargesArrivedToDrain
        function chargesArrivedToDrainButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            clear corr_parz;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            %%% numero di cariche arrivate al drain al variare del tempo, avendo fissato V=exp(0.04*10) e Vg=0.5
            app.Plot.Title.String='number of charges arrived to the drain in relation to time, V_d =exp(0.04*10)';
            plot(app.Plot, curd(:,10));
            xlabel (app.Plot, 'Time');
            ylabel (app.Plot, 'n_{left \rightarrow drain}');
        end

        % Button pushed function: cargesLeftTheSource
        function cargesLeftTheSourceButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            clear corr_parz;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            %%% numero di cariche uscite dal source al variare del tempo, avendo fissato V=exp(0.04*10) e Vg=0.5
            app.Plot.Title.String='number of charges that left the source in relation to time, V_d = exp(0.04*10)';
            plot(app.Plot, curs(:,10))
            xlabel (app.Plot, 'Time');
            ylabel (app.Plot, 'n_{source \rightarrow right}');
        end

        % Button pushed function: netCharges
        function netChargesPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            clear corr_parz;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N, exp(0.04*i)-1, app.GatevoltageVgEditField.Value);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            %%% somma algebrica del numero di cariche di cui ai due punti precedenti
            app.Plot.Title.String='Sum of the number of charges  that left the source and arrived to the drain ';
            plot(app.Plot, curs(:,10)+curd(:,10))
            xlabel (app.Plot, 'Time');
            ylabel (app.Plot, 'Charges');
        end

        % Button pushed function: drainCurrentVaryVg
        function drainCurrentVaryVgPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            clear corrente;
            for i = 1:50
                corrente(i) = master_equation(app.DrainVoltageVdEditField.Value, exp(0.04*i)-1, app.n.Value);
            end;
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N,app.DrainVoltageVdEditField.Value, exp(0.04*i)-1);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            %%%% plot della corrente di drain (curd) al variare di Vg, confronto col risultato master equation
            app.Plot.Title.String = 'Plot drain current to vary of V_g, comparison with master equation result';
            plot(app.Plot, exp(0.04*[1:50])-1, (curd(length(curd),:))./sum(t(:,:)), exp(0.04*[1:50])-1, corrente);
            xlabel (app.Plot, 'Gate voltage (V_g)');
            ylabel (app.Plot, 'Drain current (I_d)');
            legend (app.Plot, 'Monte Carlo', 'Master Equations');
        end

        % Button pushed function: drainCurrentVsTime
        function drainCurrentVsTimeButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            N = 1000;
            for i = 1:50
                [tempo,q,s,d] = arrivi(N,app.DrainVoltageVdEditField.Value, exp(0.04*i)-1);
                curd(:,i) = d(:,1);
                curs(:,i) = s(:,1);
                t(:,i) = tempo(:,1);
                car(:,i) = q(:,1);
            end;
            for i = 1:N
                corr_parz(i) = curd(i,10)/sum(t(1:i,10));
            end;
            % %%%% plot della corrente di drain (curd), al variare del tempo, avendo fissato Vg=exp(0.04*10) e V=0.4
            app.Plot.Title.String='Plot drain current in relation to time, V_g = exp(0.04*10)';
            plot(app.Plot, corr_parz)
            xlabel (app.Plot, 'Time');
            ylabel (app.Plot, 'Drain current (I_d)');
        end

        % Button pushed function: masterEquationVsMonteCarlo
        function masterEquationVsMonteCarloButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            clear corrente;
            for i = 1:50
                for j = 1:20
                    corrente(i,j) = master_equation(exp(0.04*j)-1,exp(0.04*i)-1,app.n.Value);
                end;
            end;
            N=10000;
            clear t;
            clear curd;
            clear curs;
            clear car;
            for i = 1:50
                for j = 1:20
                    [tempo,q,s,d] = arrivi(N,exp(0.04*j)-1,exp(0.04*i)-1);
                    curd(i,j) = d(length(d))/sum(tempo);
                    curs(i,j) = s(length(s))/sum(tempo);
                    car(i,j) = mean(q);
                end;
            end;
            app.Plot.Title.String='Montecarlo vs Master equations result';
            hold(app.Plot, 'off');
            surf(app.Plot, exp(0.04*[1:20])-1, exp(0.04*[1:50])-1, corrente);
            hold(app.Plot, 'on');
            %%% confronto risultati Montecarlo e Master equations
            surf(app.Plot, exp(0.04*[1:20])-1, exp(0.04*[1:50])-1, curd);
            hold(app.Plot, 'off');
        end

        % Button pushed function: chargeStored
        function chargeStoredButtonPushed(app, event)
            %clf(app.Plot);
            reset(app.Plot);
            hold(app.Plot, 'off');
            N=10000;
            for i = 1:50
                for j = 1:20
                    [tempo,q,s,d] = arrivi(N,exp(0.04*j)-1,exp(0.04*i)-1);
                    curd(i,j) = d(length(d))/sum(tempo);
                    curs(i,j) = s(length(s))/sum(tempo);
                    car(i,j) = mean(q);
                end;
            end;
            app.Plot.Title.String='Charge stored in the dot, to vary of V_d and V_g';
            %carica immagazzinata nel Dot, al variar di V e Vg
            surf(app.Plot, exp(0.04*[1:20])-1, exp(0.04*[1:50])-1, car);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Gamma = uibutton(app.UIFigure, 'push');
            app.Gamma.ButtonPushedFcn = createCallbackFcn(app, @GammaPushed, true);
            app.Gamma.Text = 'Plot Γ';
            app.Plot = uiaxes(app.UIFigure);
            title(app.Plot, 'Title')
            xlabel(app.Plot, 'X')
            ylabel(app.Plot, 'Y')
            app.MaximumnumberofelectronsstoredinthedotnLabel = uilabel(app.UIFigure);
            app.MaximumnumberofelectronsstoredinthedotnLabel.Text = 'Maximum number of electrons stored in the dot (n)';
            app.n = uispinner(app.UIFigure);
            app.n.Value = 10;
            app.GatevoltageVgEditFieldLabel = uilabel(app.UIFigure);
            app.GatevoltageVgEditFieldLabel.Text = 'Gate voltage (Vg)';
            app.GatevoltageVgEditField = uieditfield(app.UIFigure, 'numeric');
            app.GatevoltageVgEditField.Value = 5;
            app.DrainVoltageVdEditFieldLabel = uilabel(app.UIFigure);
            app.DrainVoltageVdEditFieldLabel.Text = 'Drain Voltage (Vd)';
            app.DrainVoltageVdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrainVoltageVdEditField.Value = 2;
            app.GammapVd = uibutton(app.UIFigure, 'push');
            app.GammapVd.ButtonPushedFcn = createCallbackFcn(app, @GammapVdButtonPushed, true);
            app.GammapVd.Text = 'Plot Γ*p(n) in relation of Vd';
            app.GammapVg = uibutton(app.UIFigure, 'push');
            app.GammapVg.ButtonPushedFcn = createCallbackFcn(app, @GammapVgButtonPushed, true);
            app.GammapVg.Text = 'Plot Γ*p(n) in relation of Vg';
            app.IdvsVd = uibutton(app.UIFigure, 'push');
            app.IdvsVd.ButtonPushedFcn = createCallbackFcn(app, @IdvsVdPushed, true);
            app.IdvsVd.Text = {'Plot Id to vary of Vd, Vg is fixed,'; 'comparison with master equation result'};
            app.IdvsT = uibutton(app.UIFigure, 'push');
            app.IdvsT.ButtonPushedFcn = createCallbackFcn(app, @IdvsTButtonPushed, true);
            app.IdvsT.Text = {'Plot Id in relation to time,'; 'Vd=exp(0.04*10)'};
            app.chargesArrivedToDrain = uibutton(app.UIFigure, 'push');
            app.chargesArrivedToDrain.ButtonPushedFcn = createCallbackFcn(app, @chargesArrivedToDrainButtonPushed, true);
            app.chargesArrivedToDrain.Text = {'Plot number of charges arrived to drain'; ' in relation to time,Vd=exp(0.04*10) '};
            app.cargesLeftTheSource = uibutton(app.UIFigure, 'push');
            app.cargesLeftTheSource.ButtonPushedFcn = createCallbackFcn(app, @cargesLeftTheSourceButtonPushed, true);
            app.cargesLeftTheSource.Text = {'Plot number of charges left the source '; 'in relation to time,Vd=exp(0.04*10) '};
            app.netCharges = uibutton(app.UIFigure, 'push');
            app.netCharges.ButtonPushedFcn = createCallbackFcn(app, @netChargesPushed, true);
            app.netCharges.Text = {'Plot sum of the number of charges'; ' that left the source and '; 'arrived to the drain '};
            app.drainCurrentVaryVg = uibutton(app.UIFigure, 'push');
            app.drainCurrentVaryVg.ButtonPushedFcn = createCallbackFcn(app, @drainCurrentVaryVgPushed, true);
            app.drainCurrentVaryVg.Text = {'Plot drain current to vary of Vg,'; 'comparison with master equation result'};
            app.drainCurrentVsTime = uibutton(app.UIFigure, 'push');
            app.drainCurrentVsTime.ButtonPushedFcn = createCallbackFcn(app, @drainCurrentVsTimeButtonPushed, true);
            app.drainCurrentVsTime.Text = {'Plot of drain current in relation'; ' to time, Vg = exp(0,04*10)'};
            app.masterEquationVsMonteCarlo = uibutton(app.UIFigure, 'push');
            app.masterEquationVsMonteCarlo.ButtonPushedFcn = createCallbackFcn(app, @masterEquationVsMonteCarloButtonPushed, true);
            app.masterEquationVsMonteCarlo.Text = {'Plot master equation result '; 'vs Monte Carlo'};
            app.chargeStored = uibutton(app.UIFigure, 'push');
            app.chargeStored.ButtonPushedFcn = createCallbackFcn(app, @chargeStoredButtonPushed, true);
            app.chargeStored.Text = {'Plot the charge stored in the '; 'dot to vary of Vd and Vg'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = montecarlovsmasterequation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end